classdef select_CNN_training_data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectCurrentFilename   matlab.ui.control.DropDown
        SelectFileLabel         matlab.ui.control.Label
        SelectCurrentDirectory  matlab.ui.control.DropDown
        SelectDirectoryLabel    matlab.ui.control.Label
        TextArea                matlab.ui.control.TextArea
        TextAreaLabel           matlab.ui.control.Label
        SelectFiles             matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        path % path to video files
        currentDirectory % the current video directory
        files% list of files in current folder
        currentFile  % the current video file name
        vid  % structure w/ current video
        imageOutputDirecotry % this is where the training images go
        imageOutputFilename  % the name of the current training image
        labelOutputDirectory % this is where the training labels go
        labelOutputFilename  % same as imageOutput...        
    end
    
    methods (Access = private)
        function uigetdirs(app)
            % Pick multiple files or directories using Java widgets                                                                                                                                                                                                                
            % by Andrew Janke 2011                                                                                                                                                                                                                                                 
            import javax.swing.JFileChooser;

            if (nargin == 0) % Allow a null argument.                                                                                                                                                                                      
                app.path{1} = pwd;
            end

            jchooser = javaObjectEDT('javax.swing.JFileChooser', app.path{1});

            jchooser.setFileSelectionMode(JFileChooser.FILES_AND_DIRECTORIES);
            if nargin > 1
                jchooser.setDialogTitle(dialog);
            end

            jchooser.setMultiSelectionEnabled(true);

            status = jchooser.showOpenDialog([]);

            if status == JFileChooser.APPROVE_OPTION
                jFile = jchooser.getSelectedFiles();
                app.path{size(jFile, 1)}=[];
                for i=1:size(jFile, 1)
                	app.path{i} = char(jFile(i).getAbsolutePath);
                end

            elseif status == JFileChooser.CANCEL_OPTION
                app.path{1} = [];
            else
                error('Error occured while picking file.');
            end 
        app.path = app.path';   
        end
        
        function addFiles(app)
            filesAVI = dir([app.SelectCurrentDirectory.Value, filesep,'*.avi']);
            filesMP4 = dir([app.SelectCurrentDirectory.Value, filesep,'*.mp4']);
            fileNames = [filesAVI; filesMP4];
            for jj = 1:length(fileNames)
                fileName = fileNames(jj).name;
                FileItems{jj} = fileName;
            end
            app.SelectCurrentFilename.Items     = FileItems;
            app.SelectCurrentFilename.ItemsData = FileItems;
            app.SelectCurrentFilename.Value     = FileItems{1};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectFiles
        function SelectFilesPushed(app, event)
            app.path = [];
            % raw-data parent directory                                                                                                                                                                                                                                            
            %[stat,rootDir] = system('git rev-parse --show-toplevel');
            %app.path{1}    = rootDir(1:end-1);% drop the \n character  
            app.path{1} = pwd;
            uigetdirs(app)
            if isempty(app.path)
                app.TextArea.Value = "no directory selected, try again";
                return
            end

            % add these to the directory selector
            for jj = 1:length(app.path)
                FolderNames = regexp(app.path{jj},filesep,'split');
                DirectoryItems{jj} = [char(FolderNames{end-1}), filesep,char(FolderNames{end})];
            end
            app.SelectCurrentDirectory.Items     = DirectoryItems;
            app.SelectCurrentDirectory.ItemsData = app.path;
            app.SelectCurrentDirectory.Value     = app.path{1};
            %
            % add the filenames to the list
            addFiles(app);
            
            
            %fapp.SelectCurrentFilename.ItemsData
            
        end

        % Value changed function: SelectCurrentFilename
        function SelectCurrentFilenameValueChanged(app, event)
            value = app.SelectCurrentFilename.Value;
            %workingDir = app.SelectCurrentDirectory;
            %workingDir = char(workingDir(1))
            videoPath = [app.SelectCurrentDirectory.Value, filesep, app.SelectCurrentFilename.Value];
            [ocean,oceanSum,oceanX,oceanY,infoOCM] = rectify_vd(videoPath,value,'rod13')
        end

        % Drop down opening function: SelectCurrentFilename
        function SelectCurrentFilenameDropDownOpening(app, event)
            addFiles(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SelectFiles = uibutton(app.UIFigure, 'push');
            app.SelectFiles.ButtonPushedFcn = createCallbackFcn(app, @SelectFilesPushed, true);
            app.SelectFiles.Text = 'Select Video Files';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.SelectDirectoryLabel = uilabel(app.UIFigure);
            app.SelectDirectoryLabel.Text = 'Select Directory:';
            app.SelectCurrentDirectory = uidropdown(app.UIFigure);
            app.SelectFileLabel = uilabel(app.UIFigure);
            app.SelectFileLabel.Text = 'Select File:';
            app.SelectCurrentFilename = uidropdown(app.UIFigure);
            app.SelectCurrentFilename.DropDownOpeningFcn = createCallbackFcn(app, @SelectCurrentFilenameDropDownOpening, true);
            app.SelectCurrentFilename.ValueChangedFcn = createCallbackFcn(app, @SelectCurrentFilenameValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = select_CNN_training_data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end